classdef ICPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICProUIFigure            matlab.ui.Figure
        SampletypeSwitchLabel    matlab.ui.control.Label
        Panel_TE                 matlab.ui.container.Panel
        Button_RawFile           matlab.ui.control.Button
        Field_RawFile            matlab.ui.control.EditField
        Field_SIDTE              matlab.ui.control.EditField
        Field_BlankID            matlab.ui.control.EditField
        Field_SpikeID            matlab.ui.control.EditField
        Label_SIDTE              matlab.ui.control.Label
        Label_BlankID            matlab.ui.control.Label
        Label_SpikeID            matlab.ui.control.Label
        Field_RinseID            matlab.ui.control.EditField
        Label_RinseID            matlab.ui.control.Label
        Field_QCID               matlab.ui.control.EditField
        Label_QCID               matlab.ui.control.Label
        Panel_TECorr             matlab.ui.container.Panel
        Check_RC                 matlab.ui.control.CheckBox
        Check_QC                 matlab.ui.control.CheckBox
        Check_SC                 matlab.ui.control.CheckBox
        Check_OC                 matlab.ui.control.CheckBox
        Check_BC                 matlab.ui.control.CheckBox
        Check_DC                 matlab.ui.control.CheckBox
        Panel_ThU                matlab.ui.container.Panel
        Field_NBlocks            matlab.ui.control.EditField
        Field_NSamples           matlab.ui.control.EditField
        Label_NBlocks            matlab.ui.control.Label
        Label_NSamples           matlab.ui.control.Label
        Field_ICPBlankID         matlab.ui.control.EditField
        Label_ICPBlankID         matlab.ui.control.Label
        Field_ICPNatUID          matlab.ui.control.EditField
        Label_ICPNatUID          matlab.ui.control.Label
        Field_SIDThU             matlab.ui.control.EditField
        Label_SIDThU             matlab.ui.control.Label
        Button_RawFolder         matlab.ui.control.Button
        Field_RawFolder          matlab.ui.control.EditField
        Panel_IDweights          matlab.ui.container.Panel
        SpikesPanel              matlab.ui.container.Panel
        UspikeDropDownLabel      matlab.ui.control.Label
        DropDown_236U            matlab.ui.control.DropDown
        ThspikeDropDown_2Label   matlab.ui.control.Label
        DropDown_230Th           matlab.ui.control.DropDown
        ThspikeDropDownLabel     matlab.ui.control.Label
        DropDown_229Th           matlab.ui.control.DropDown
        Field_229ThRY            matlab.ui.control.NumericEditField
        Label_RX                 matlab.ui.control.Label
        Field_229ThRX            matlab.ui.control.NumericEditField
        Label_CY                 matlab.ui.control.Label
        Field_229ThCY            matlab.ui.control.NumericEditField
        Field_230ThRY            matlab.ui.control.NumericEditField
        Field_236URY             matlab.ui.control.NumericEditField
        Field_230ThRX            matlab.ui.control.NumericEditField
        Field_236URX             matlab.ui.control.NumericEditField
        Field_230ThCY            matlab.ui.control.NumericEditField
        Field_236UCY             matlab.ui.control.NumericEditField
        Label_RY                 matlab.ui.control.Label
        Button_Edit229Th         matlab.ui.control.Button
        Button_Edit230Th         matlab.ui.control.Button
        Button_Edit236U          matlab.ui.control.Button
        Button_Save229Th         matlab.ui.control.Button
        Button_Save230Th         matlab.ui.control.Button
        Button_Save236U          matlab.ui.control.Button
        Button_Remove229Th       matlab.ui.control.Button
        Button_Remove230Th       matlab.ui.control.Button
        Button_Remove236U        matlab.ui.control.Button
        Footnote                 matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        WeightsmgTab             matlab.ui.container.Tab
        Field_Sediment           matlab.ui.control.TextArea
        Label_Sediment           matlab.ui.control.Label
        Field_4ml                matlab.ui.control.TextArea
        Field_10ml               matlab.ui.control.TextArea
        Field_400ul              matlab.ui.control.TextArea
        Field_ThBC               matlab.ui.control.TextArea
        Label_4ml                matlab.ui.control.Label
        Label_10ml               matlab.ui.control.Label
        Label_400ul              matlab.ui.control.Label
        Label_ThBC               matlab.ui.control.Label
        Field_UBC                matlab.ui.control.TextArea
        Field_ThSC               matlab.ui.control.TextArea
        Label_UBC                matlab.ui.control.Label
        Label_ThSC               matlab.ui.control.Label
        Field_USC                matlab.ui.control.TextArea
        Label_USC                matlab.ui.control.Label
        Field_SampleList         matlab.ui.control.TextArea
        Label_SampleList         matlab.ui.control.Label
        Button_MakeList          matlab.ui.control.Button
        ThUrawintensitiescpsTab  matlab.ui.container.Tab
        Field_cpsSampleList      matlab.ui.control.TextArea
        Label_cpsSampleList      matlab.ui.control.Label
        Field_cps230Th           matlab.ui.control.TextArea
        Label_cps230Th           matlab.ui.control.Label
        Field_cps232Th           matlab.ui.control.TextArea
        Field_cps236U            matlab.ui.control.TextArea
        Field_cps238U            matlab.ui.control.TextArea
        Label_cps232Th           matlab.ui.control.Label
        Label_cps236U            matlab.ui.control.Label
        Label_cps238U            matlab.ui.control.Label
        Label_cps230Th_R         matlab.ui.control.Label
        Label_cps232Th_R         matlab.ui.control.Label
        Label_cps236U_R          matlab.ui.control.Label
        Label_cps238U_R          matlab.ui.control.Label
        Field_cps230Th_R         matlab.ui.control.TextArea
        Field_cps232Th_R         matlab.ui.control.TextArea
        Field_cps236U_R          matlab.ui.control.TextArea
        Field_cps238U_R          matlab.ui.control.TextArea
        Footnote2                matlab.ui.control.Label
        Button_TEtoggle          matlab.ui.control.StateButton
        Button_ThUtoggle         matlab.ui.control.StateButton
        Button_Run               matlab.ui.control.Button
        Switch_SType             matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Run
        function Execute(app, event)
            AppPath = dir([mfilename('fullpath'),'.mlapp']);
            cd([AppPath.folder,'/functions/'])
            %% Gather Input data
            % Trace element parameters
            TEpar.RawPath = app.Field_RawFile.Value;
            TEpar.SID = app.Field_SIDTE.Value;
            TEpar.BID = app.Field_BlankID.Value;
            TEpar.VID = app.Field_SpikeID.Value;
            TEpar.RID = app.Field_RinseID.Value;
            TEpar.QID = app.Field_QCID.Value;
            TEpar.CCheck.RC = app.Check_RC.Value;
            TEpar.CCheck.QC = app.Check_QC.Value;
            TEpar.CCheck.SC = app.Check_SC.Value;
            TEpar.CCheck.OC = app.Check_OC.Value;
            TEpar.CCheck.BC = app.Check_BC.Value;
            TEpar.CCheck.DC = app.Check_DC.Value;
            % Th/U parameters
            ThUpar.ThURawPath = app.Field_RawFolder.Value;
            ThUpar.SType = app.Switch_SType.Value;
            ThUpar.NBlocks = str2double(app.Field_NBlocks.Value);
            ThUpar.NSamples = str2double(app.Field_NSamples.Value);
            ThUpar.SID = app.Field_SIDThU.Value;
            ThUpar.BID = app.Field_ICPBlankID.Value;
            ThUpar.NatUID = app.Field_ICPNatUID.Value;
            ThUpar.IDsel = 0;
            % Isotopic dilution parameters (weights, spikes & raw intensities)
            WtPar.m_sed = str2double(app.Field_Sediment.Value)';
            WtPar.m_4ml = str2double(app.Field_4ml.Value)';
            WtPar.m_10ml = str2double(app.Field_10ml.Value)';
            WtPar.m_400ul = str2double(app.Field_400ul.Value)';
            WtPar.m_ThSC = str2double(app.Field_ThSC.Value)';
            WtPar.m_ThBC = str2double(app.Field_ThBC.Value)';
            WtPar.m_USC = str2double(app.Field_USC.Value)';
            WtPar.m_UBC = str2double(app.Field_UBC.Value)';
            SpikePar.Th229RY = app.Field_229ThRY.Value;
            SpikePar.Th229RX = app.Field_229ThRX.Value;
            SpikePar.Th229CY = app.Field_229ThCY.Value;
            SpikePar.Th230RY = app.Field_230ThRY.Value;
            SpikePar.Th230RX = app.Field_230ThRX.Value;
            SpikePar.Th230CY = app.Field_230ThCY.Value;
            SpikePar.U236RY = app.Field_236URY.Value;
            SpikePar.U236RX = app.Field_236URX.Value;
            SpikePar.U236CY = app.Field_236UCY.Value;
            cps.Sample = [str2double(app.Field_cps230Th.Value),...
                str2double(app.Field_cps232Th.Value),...
                str2double(app.Field_cps236U.Value),...
                str2double(app.Field_cps238U.Value)]';
            cps.Rinse = [str2double(app.Field_cps230Th_R.Value),...
                str2double(app.Field_cps232Th_R.Value),...
                str2double(app.Field_cps236U_R.Value),...
                str2double(app.Field_cps238U_R.Value)]';
            
            %% Data processing
            if app.Button_ThUtoggle.Value == 0 && app.Button_TEtoggle.Value == 1
                % Read & process TE raw data
                [C_RAW,dC_RAW,Isotopes,RunID] = TE_ReadRaw(TEpar,1);
                [C, dC] = TE_Processor(TEpar,C_RAW,dC_RAW,Isotopes,RunID,WtPar,SpikePar,cps);
                C
                dC
            elseif app.Button_ThUtoggle.Value == 1 && app.Button_TEtoggle.Value == 0
                RatioCal(ThUpar)
            elseif app.Button_ThUtoggle.Value == 1 && app.Button_TEtoggle.Value == 1
                warndlg('Stay tuned!')
            else
                warndlg('No analyses performed.')
            end
        end

        % Value changed function: Button_TEtoggle
        function ToggleTE(app, event)
            value = app.Button_TEtoggle.Value;
            if value == 0
                % Change color and icon
                set(app.Button_TEtoggle,'Icon','misc/icons/x.png')
                set(app.Button_TEtoggle,'BackgroundColor',[1 .5 .5])
                % Disable panel
                set(app.Panel_TE,'Enable','off')
                % Disable selected weight input fields
                if app.Button_ThUtoggle.Value == 0
                    set(app.Panel_IDweights,'Enable','off')
                    set(app.Field_4ml,'Enable','on')
                    set(app.Field_10ml,'Enable','on')
                    set(app.Field_400ul,'Enable','on')
                    set(app.Field_ThSC,'Enable','on')
                    set(app.Field_USC,'Enable','on')
                    set(app.Field_ThBC,'Enable','on')
                    set(app.Field_UBC,'Enable','on')
                    set(app.DropDown_229Th,'Enable','on')
                    set(app.Field_229ThRY,'Enable','on')
                    set(app.Field_229ThRX,'Enable','on')
                    set(app.Field_229ThCY,'Enable','on')
                else
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_4ml,'Enable','off')
                    set(app.Field_10ml,'Enable','off')
                    set(app.Field_400ul,'Enable','off')
                    set(app.Field_ThSC,'Enable','off')
                    set(app.Field_USC,'Enable','off')
                    set(app.DropDown_230Th,'Enable','off')
                    set(app.Field_230ThRY,'Enable','off')
                    set(app.Field_230ThRX,'Enable','off')
                    set(app.Field_230ThCY,'Enable','off')
                    set(app.Field_230ThCY,'Enable','off')
                    if isempty(app.DropDown_230Th.ItemsData)
                        app.DropDown_230Th.Value = 'Select';
                    else
                        app.DropDown_230Th.Value = 0;
                    end
                    set(app.Field_230ThRY,'Value',0)
                    set(app.Field_230ThRX,'Value',0)
                    set(app.Field_230ThCY,'Value',0)
                    set(app.Field_230ThCY,'Editable','off')
                    set(app.Field_230ThRX,'Editable','off')
                    set(app.Field_230ThRY,'Editable','off')
                    set(app.Button_Edit230Th,'Visible','on')
                    set(app.Button_Edit230Th,'Enable','off')
                    set(app.Button_Remove230Th,'Enable','off')
                    set(app.Button_Save230Th,'Visible','off')
                    set(app.Button_Save230Th,'Enable','off')
                end
            else
                % Change color and icon
                set(app.Button_TEtoggle,'Icon','misc/icons/tick.png')
                set(app.Button_TEtoggle,'BackgroundColor',[.5 1 .5])
                % Enable panel
                set(app.Panel_TE,'Enable','on')
                % Enable selected weight input fields
                if app.Button_ThUtoggle.Value == 0 && app.Check_DC.Value == 1
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_ThBC,'Enable','off')
                    set(app.DropDown_229Th,'Enable','off')
                    set(app.Field_229ThRY,'Enable','off')
                    set(app.Field_229ThRX,'Enable','off')
                    set(app.Field_229ThCY,'Enable','off')
                elseif app.Button_ThUtoggle.Value == 0 && app.Check_DC.Value == 0
                    set(app.Panel_IDweights,'Enable','off')
                    set(app.Field_4ml,'Enable','on')
                    set(app.Field_10ml,'Enable','on')
                    set(app.Field_400ul,'Enable','on')
                    set(app.Field_ThSC,'Enable','on')
                    set(app.Field_USC,'Enable','on')
                    set(app.Field_ThBC,'Enable','on')
                    set(app.Field_UBC,'Enable','on')
                    set(app.DropDown_229Th,'Enable','on')
                    set(app.Field_229ThRY,'Enable','on')
                    set(app.Field_229ThRX,'Enable','on')
                    set(app.Field_229ThCY,'Enable','on')
                elseif app.Button_ThUtoggle.Value == 1 && app.Check_DC.Value == 0
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_4ml,'Enable','off')
                    set(app.Field_10ml,'Enable','off')
                    set(app.Field_400ul,'Enable','off')
                    set(app.Field_ThSC,'Enable','off')
                    set(app.Field_USC,'Enable','off')
                    %                     set(app.DropDown_229Th,'Enable','off')
                    %                     set(app.Field_229ThRY,'Enable','off')
                    %                     set(app.Field_229ThRX,'Enable','off')
                    %                     set(app.Field_229ThCY,'Enable','off')
                else
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_4ml,'Enable','on')
                    set(app.Field_10ml,'Enable','on')
                    set(app.Field_400ul,'Enable','on')
                    set(app.Field_ThSC,'Enable','on')
                    set(app.Field_USC,'Enable','on')
                    set(app.Field_ThBC,'Enable','on')
                    set(app.Field_UBC,'Enable','on')
                    set(app.DropDown_230Th,'Enable','on')
                    set(app.DropDown_230Th,'FontColor',[0 0 0])
                    set(app.Field_230ThRY,'Enable','on')
                    set(app.Field_230ThRX,'Enable','on')
                    set(app.Field_230ThCY,'Enable','on')
                end
            end
        end

        % Value changed function: Button_ThUtoggle
        function ToggleThU(app, event)
            value = app.Button_ThUtoggle.Value;
            if value == 0
                % Change color and icon
                set(app.Button_ThUtoggle,'Icon','misc/icons/x.png')
                set(app.Button_ThUtoggle,'BackgroundColor',[1 .5 .5])
                % Disable Th/U panel
                set(app.Panel_ThU,'Enable','off')
                if app.Button_TEtoggle.Value == 0 || (app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 0)
                    set(app.Panel_IDweights,'Enable','off')
                    set(app.Field_4ml,'Enable','on')
                    set(app.Field_10ml,'Enable','on')
                    set(app.Field_400ul,'Enable','on')
                    set(app.Field_ThSC,'Enable','on')
                    set(app.Field_USC,'Enable','on')
                    set(app.Field_ThBC,'Enable','on')
                    set(app.Field_UBC,'Enable','on')
                    set(app.DropDown_230Th,'Enable','on')
                    set(app.Field_230ThRY,'Enable','on')
                    set(app.Field_230ThRX,'Enable','on')
                    set(app.Field_230ThCY,'Enable','on')
                elseif app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 1
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_ThBC,'Enable','off')
                    set(app.DropDown_229Th,'Enable','off')
                    set(app.Field_229ThRY,'Enable','off')
                    set(app.Field_229ThRX,'Enable','off')
                    set(app.Field_229ThCY,'Enable','off')
                    set(app.Field_229ThCY,'Enable','off')
                    if isempty(app.DropDown_229Th.ItemsData)
                        app.DropDown_229Th.Value = 'Select';
                    else
                        app.DropDown_229Th.Value = 0;
                    end
                    set(app.Field_229ThRY,'Value',0)
                    set(app.Field_229ThRX,'Value',0)
                    set(app.Field_229ThCY,'Value',0)
                    set(app.Field_229ThCY,'Editable','off')
                    set(app.Field_229ThRX,'Editable','off')
                    set(app.Field_229ThRY,'Editable','off')
                    set(app.Button_Edit229Th,'Visible','on')
                    set(app.Button_Edit229Th,'Enable','off')
                    set(app.Button_Remove229Th,'Enable','off')
                    set(app.Button_Save229Th,'Visible','off')
                    set(app.Button_Save229Th,'Enable','off')
                end
            else
                % Change color and icon
                set(app.Button_ThUtoggle,'Icon','misc/icons/tick.png')
                set(app.Button_ThUtoggle,'BackgroundColor',[.5 1 .5])
                % Disable panel
                set(app.Panel_ThU,'Enable','on')
                if app.Button_TEtoggle.Value == 0 || (app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 0)
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_4ml,'Enable','off')
                    set(app.Field_10ml,'Enable','off')
                    set(app.Field_400ul,'Enable','off')
                    set(app.Field_ThSC,'Enable','off')
                    set(app.Field_USC,'Enable','off')
                    set(app.DropDown_230Th,'Enable','off')
                    set(app.Field_230ThRY,'Enable','off')
                    set(app.Field_230ThRX,'Enable','off')
                    set(app.Field_230ThCY,'Enable','off')
                elseif app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 1
                    set(app.Panel_IDweights,'Enable','on')
                    set(app.Field_ThBC,'Enable','on')
                    set(app.DropDown_229Th,'Enable','on')
                    set(app.Field_229ThRY,'Enable','on')
                    set(app.Field_229ThRX,'Enable','on')
                    set(app.Field_229ThCY,'Enable','on')
                    set(app.DropDown_230Th,'Enable','on')
                    set(app.Field_230ThRY,'Enable','on')
                    set(app.Field_230ThRX,'Enable','on')
                    set(app.Field_230ThCY,'Enable','on')
                end
            end
        end

        % Button pushed function: Button_RawFile
        function BrowseTE(app, event)
            [TMRawFile,TMRawPath] = uigetfile('.xlsx','Pick a Trace Element raw file');
            if TMRawPath ~= 0
                set(app.Field_RawFile,'Value',[TMRawPath,TMRawFile])
            end
        end

        % Button pushed function: Button_RawFolder
        function BrowseThU(app, event)
            ThURawPath = uigetdir('','Pick a Th/U raw data folder');
            if ThURawPath ~= 0
                set(app.Field_RawFolder,'Value',[ThURawPath,'/'])
            end
        end

        % Value changing function: Field_SIDTE
        function AutoFillSampleID(app, event)
            changingValue = event.Value;
            set(app.Field_SIDThU,'Value',changingValue)
        end

        % Button pushed function: Button_MakeList
        function MakeSampleList(app, event)
            if app.Button_TEtoggle.Value == 1 && app.Check_DC.Value == 1
                TEpar.RawPath = app.Field_RawFile.Value;
                TEpar.SID = app.Field_SIDTE.Value;
                TEpar.BID = app.Field_BlankID.Value;
                TEpar.VID = app.Field_SpikeID.Value;
                TEpar.RID = app.Field_RinseID.Value;
                TEpar.QID = app.Field_QCID.Value;
                if app.Button_TEtoggle.Value == 1
                    [~,~,~,RunID] = TE_ReadRaw(TEpar,0);
                end
                set(app.Button_MakeList,'Visible','off')
                set(app.Field_SampleList,'Visible','on')
                set(app.Field_SampleList,'Value',RunID.Sample)
                set(app.Field_cpsSampleList,'Visible','on')
                set(app.Field_cpsSampleList,'Value',RunID.Sample)
            elseif app.Button_ThUtoggle.Value == 1 && (app.Check_DC == 0 || app.Button_TEtoggle.Value == 0)
                FileList = dir([app.Field_RawFolder.Value,'*.txt']);
                RunID.all = {FileList.name}';
                RunID.Sample = RunID.all(contains(RunID.all,app.Field_SIDThU.Value));
                set(app.Button_MakeList,'Visible','off')
                set(app.Field_SampleList,'Visible','on')
                set(app.Field_SampleList,'Value',RunID.Sample)
                set(app.Field_cpsSampleList,'Visible','on')
                set(app.Field_cpsSampleList,'Value',RunID.Sample)
            end
        end

        % Value changed function: Check_DC
        function DCchange(app, event)
            value = app.Check_DC.Value;
            if value == 0 && app.Button_ThUtoggle.Value == 0
                set(app.Panel_IDweights,'Enable','off')
                set(app.Field_4ml,'Enable','on')
                set(app.Field_10ml,'Enable','on')
                set(app.Field_400ul,'Enable','on')
                set(app.Field_ThSC,'Enable','on')
                set(app.Field_USC,'Enable','on')
                set(app.Field_ThBC,'Enable','on')
                set(app.DropDown_229Th,'Enable','on')
                set(app.Field_229ThRY,'Enable','on')
                set(app.Field_229ThRX,'Enable','on')
                set(app.Field_229ThCY,'Enable','on')
                set(app.DropDown_230Th,'Enable','on')
                set(app.Field_230ThRY,'Enable','on')
                set(app.Field_230ThRX,'Enable','on')
                set(app.Field_230ThCY,'Enable','on')
                if isempty(app.DropDown_230Th.ItemsData)
                    app.DropDown_230Th.Value = 'Select';
                else
                    app.DropDown_230Th.Value = 0;
                end
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            elseif value == 0 && app.Button_ThUtoggle.Value == 1
                set(app.Panel_IDweights,'Enable','on')
                set(app.Field_4ml,'Enable','off')
                set(app.Field_10ml,'Enable','off')
                set(app.Field_400ul,'Enable','off')
                set(app.Field_ThSC,'Enable','off')
                set(app.Field_USC,'Enable','off')
                set(app.DropDown_229Th,'Enable','on')
                set(app.Field_229ThRY,'Enable','on')
                set(app.Field_229ThRX,'Enable','on')
                set(app.Field_229ThCY,'Enable','on')
                set(app.DropDown_230Th,'Enable','off')
                set(app.Field_230ThRY,'Enable','off')
                set(app.Field_230ThRX,'Enable','off')
                set(app.Field_230ThCY,'Enable','off')
                if isempty(app.DropDown_230Th.ItemsData)
                    app.DropDown_230Th.Value = 'Select';
                else
                    app.DropDown_230Th.Value = 0;
                end
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            elseif value == 1 && app.Button_ThUtoggle.Value == 0
                set(app.Panel_IDweights,'Enable','on')
                set(app.Field_4ml,'Enable','on')
                set(app.Field_10ml,'Enable','on')
                set(app.Field_400ul,'Enable','on')
                set(app.Field_ThSC,'Enable','on')
                set(app.Field_USC,'Enable','on')
                set(app.Field_ThBC,'Enable','off')
                set(app.DropDown_229Th,'Enable','off')
                set(app.Field_229ThRY,'Enable','off')
                set(app.Field_229ThRX,'Enable','off')
                set(app.Field_229ThCY,'Enable','off')
                set(app.DropDown_230Th,'Enable','on')
                set(app.Field_230ThRY,'Enable','on')
                set(app.Field_230ThRX,'Enable','on')
                set(app.Field_230ThCY,'Enable','on')
            else
                set(app.Panel_IDweights,'Enable','on')
                set(app.Field_4ml,'Enable','on')
                set(app.Field_10ml,'Enable','on')
                set(app.Field_400ul,'Enable','on')
                set(app.Field_ThSC,'Enable','on')
                set(app.Field_USC,'Enable','on')
                set(app.Field_ThBC,'Enable','on')
                set(app.DropDown_229Th,'Enable','on')
                set(app.Field_229ThRY,'Enable','on')
                set(app.Field_229ThRX,'Enable','on')
                set(app.Field_229ThCY,'Enable','on')
                set(app.DropDown_230Th,'Enable','on')
                set(app.Field_230ThRY,'Enable','on')
                set(app.Field_230ThRX,'Enable','on')
                set(app.Field_230ThCY,'Enable','on')
            end
        end

        % Drop down opening function: DropDown_229Th
        function getSpike229Th(app, event)
            opts = spreadsheetImportOptions('NumVariables',4,'Sheet','Th229','DataRange','A2','VariableNamesRange','A1');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            set(app.DropDown_229Th,'Items',['Select';spikes{:,1};'Add'])
            set(app.DropDown_229Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
            %             set(app.Field_229ThRY,'Value',0)
            %             set(app.Field_229ThRX,'Value',0)
            %             set(app.Field_229ThCY,'Value',0)
            %             set(app.Field_229ThCY,'Editable','off')
            %             set(app.Field_229ThRX,'Editable','off')
            %             set(app.Field_229ThRY,'Editable','off')
            %             set(app.Button_Edit229Th,'Visible','on')
            %             set(app.Button_Edit229Th,'Enable','off')
            %             set(app.Button_Save229Th,'Visible','off')
            %             set(app.Button_Save229Th,'Enable','off')
        end

        % Value changed function: DropDown_229Th
        function setSpike229Th(app, event)
            value = app.DropDown_229Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th229');
            if value > 0 && value < 999
                set(app.Field_229ThRY,'Value',spikes{value,2})
                set(app.Field_229ThRX,'Value',spikes{value,3})
                set(app.Field_229ThCY,'Value',spikes{value,4})
                set(app.Field_229ThCY,'Editable','off')
                set(app.Field_229ThRX,'Editable','off')
                set(app.Field_229ThRY,'Editable','off')
                set(app.Button_Edit229Th,'Visible','on')
                set(app.Button_Edit229Th,'Enable','on')
                set(app.Button_Remove229Th,'Enable','on')
                set(app.Button_Save229Th,'Visible','off')
                set(app.Button_Save229Th,'Enable','off')
            elseif value == 0
                set(app.Field_229ThRY,'Value',0)
                set(app.Field_229ThRX,'Value',0)
                set(app.Field_229ThCY,'Value',0)
                set(app.Field_229ThCY,'Editable','off')
                set(app.Field_229ThRX,'Editable','off')
                set(app.Field_229ThRY,'Editable','off')
                set(app.Button_Edit229Th,'Visible','on')
                set(app.Button_Edit229Th,'Enable','off')
                set(app.Button_Remove229Th,'Enable','off')
                set(app.Button_Save229Th,'Visible','off')
                set(app.Button_Save229Th,'Enable','off')
            elseif value == 999
                set(app.Field_229ThCY,'Editable','on')
                set(app.Field_229ThRX,'Editable','on')
                set(app.Field_229ThRY,'Editable','on')
                set(app.Button_Edit229Th,'Visible','off')
                set(app.Button_Edit229Th,'Enable','off')
                set(app.Button_Remove229Th,'Enable','off')
                set(app.Button_Save229Th,'Visible','on')
                set(app.Button_Save229Th,'Enable','on')
                newspikeID = inputdlg('Enter spike ID:');
                set(app.DropDown_229Th,'Items',['Select';spikes{:,1};newspikeID;'Add'])
                set(app.DropDown_229Th,'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                set(app.DropDown_229Th,'Value',length(spikes{:,1})+1)
                set(app.Field_229ThRY,'Value',0)
                set(app.Field_229ThRX,'Value',0)
                set(app.Field_229ThCY,'Value',0)
            end
        end

        % Button pushed function: Button_Edit229Th
        function edtSpike229Th(app, event)
            set(app.Field_229ThCY,'Editable','on')
            set(app.Field_229ThRX,'Editable','on')
            set(app.Field_229ThRY,'Editable','on')
            set(app.Button_Edit229Th,'Visible','off')
            set(app.Button_Edit229Th,'Enable','off')
            set(app.Button_Save229Th,'Visible','on')
            set(app.Button_Save229Th,'Enable','on')
        end

        % Button pushed function: Button_Save229Th
        function sveSpike229Th(app, event)
            value = app.DropDown_229Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            if value > length(spikes{:,1})
                spikes = [spikes;{0,0,0,0}];
                spikes{end,1} = app.DropDown_229Th.Items(app.DropDown_229Th.Value+1);
            end
            spikes{value,2} = app.Field_229ThRY.Value;
            spikes{value,3} = app.Field_229ThRX.Value;
            spikes{value,4} = app.Field_229ThCY.Value;
            writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th229','WriteMode','overwritesheet')
            set(app.Field_229ThCY,'Editable','off')
            set(app.Field_229ThRX,'Editable','off')
            set(app.Field_229ThRY,'Editable','off')
            set(app.Button_Edit229Th,'Visible','on')
            set(app.Button_Edit229Th,'Enable','on')
            set(app.Button_Remove229Th,'Enable','on')
            set(app.Button_Save229Th,'Visible','off')
            set(app.Button_Save229Th,'Enable','off')
        end

        % Button pushed function: Button_Remove229Th
        function dltSpike229Th(app, event)
            value = app.DropDown_229Th.Value;
            % Confirm dialog
            msg = ['Do you want to delete spike ''',app.DropDown_229Th.Items{value+1},''''];
            title = 'Confirm Delete';
            selection = questdlg(msg,title,'Ok','Cancel','Ok');
            if strcmp(selection,'Ok')
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th229');
                spikes(value,:) = [];
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th229','WriteMode','overwritesheet')
                set(app.DropDown_229Th,'Items',['Select';spikes{:,1};'Add'])
                set(app.DropDown_229Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
                app.DropDown_229Th.Value = 0;
                set(app.Field_229ThRY,'Value',0)
                set(app.Field_229ThRX,'Value',0)
                set(app.Field_229ThCY,'Value',0)
                set(app.Field_229ThCY,'Editable','off')
                set(app.Field_229ThRX,'Editable','off')
                set(app.Field_229ThRY,'Editable','off')
                set(app.Button_Edit229Th,'Visible','on')
                set(app.Button_Edit229Th,'Enable','off')
                set(app.Button_Remove229Th,'Enable','off')
                set(app.Button_Save229Th,'Visible','off')
                set(app.Button_Save229Th,'Enable','off')
            end
        end

        % Drop down opening function: DropDown_230Th
        function getSpike230Th(app, event)
            opts = spreadsheetImportOptions('NumVariables',4,'Sheet','Th230','DataRange','A2','VariableNamesRange','A1');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            set(app.DropDown_230Th,'Items',['Select';spikes{:,1};'Add'])
            set(app.DropDown_230Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
        end

        % Value changed function: DropDown_230Th
        function setSpike230Th(app, event)
            value = app.DropDown_230Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th230');
            if value > 0 && value < 999
                set(app.Field_230ThRY,'Value',spikes{value,2})
                set(app.Field_230ThRX,'Value',spikes{value,3})
                set(app.Field_230ThCY,'Value',spikes{value,4})
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','on')
                set(app.Button_Remove230Th,'Enable','on')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            elseif value == 0
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            elseif value == 999
                set(app.Field_230ThCY,'Editable','on')
                set(app.Field_230ThRX,'Editable','on')
                set(app.Field_230ThRY,'Editable','on')
                set(app.Button_Edit230Th,'Visible','off')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','on')
                set(app.Button_Save230Th,'Enable','on')
                newspikeID = inputdlg('Enter spike ID:');
                set(app.DropDown_230Th,'Items',['Select';spikes{:,1};newspikeID;'Add'])
                set(app.DropDown_230Th,'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                set(app.DropDown_230Th,'Value',length(spikes{:,1})+1)
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
            end
        end

        % Button pushed function: Button_Edit230Th
        function edtSpike230Th(app, event)
            set(app.Field_230ThCY,'Editable','on')
            set(app.Field_230ThRX,'Editable','on')
            set(app.Field_230ThRY,'Editable','on')
            set(app.Button_Edit230Th,'Visible','off')
            set(app.Button_Edit230Th,'Enable','off')
            set(app.Button_Save230Th,'Visible','on')
            set(app.Button_Save230Th,'Enable','on')
        end

        % Button pushed function: Button_Save230Th
        function sveSpike230Th(app, event)
            value = app.DropDown_230Th.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th230');
            if value > length(spikes{:,1})
                spikes = [spikes;{0,0,0,0}];
                spikes{end,1} = app.DropDown_230Th.Items(app.DropDown_230Th.Value+1);
            end
            spikes{value,2} = app.Field_230ThRY.Value;
            spikes{value,3} = app.Field_230ThRX.Value;
            spikes{value,4} = app.Field_230ThCY.Value;
            writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th230','WriteMode','overwritesheet')
            set(app.Field_230ThCY,'Editable','off')
            set(app.Field_230ThRX,'Editable','off')
            set(app.Field_230ThRY,'Editable','off')
            set(app.Button_Edit230Th,'Visible','on')
            set(app.Button_Edit230Th,'Enable','on')
            set(app.Button_Remove230Th,'Enable','on')
            set(app.Button_Save230Th,'Visible','off')
            set(app.Button_Save230Th,'Enable','off')
        end

        % Button pushed function: Button_Remove230Th
        function dltSpike230Th(app, event)
            value = app.DropDown_230Th.Value;
            % Confirm dialog
            msg = ['Do you want to delete spike ''',app.DropDown_230Th.Items{value+1},''''];
            title = 'Confirm Delete';
            selection = questdlg(msg,title,'Ok','Cancel','Ok');
            if strcmp(selection,'Ok')
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','Th230');
                spikes(value,:) = [];
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','Th230','WriteMode','overwritesheet')
                set(app.DropDown_230Th,'Items',['Select';spikes{:,1};'Add'])
                set(app.DropDown_230Th,'ItemsData',[0;(1:length(spikes{:,1}))';999])
                app.DropDown_230Th.Value = 0;
                set(app.Field_230ThRY,'Value',0)
                set(app.Field_230ThRX,'Value',0)
                set(app.Field_230ThCY,'Value',0)
                set(app.Field_230ThCY,'Editable','off')
                set(app.Field_230ThRX,'Editable','off')
                set(app.Field_230ThRY,'Editable','off')
                set(app.Button_Edit230Th,'Visible','on')
                set(app.Button_Edit230Th,'Enable','off')
                set(app.Button_Remove230Th,'Enable','off')
                set(app.Button_Save230Th,'Visible','off')
                set(app.Button_Save230Th,'Enable','off')
            end
        end

        % Drop down opening function: DropDown_236U
        function getSpikes236U(app, event)
            opts = spreadsheetImportOptions('NumVariables',4,'Sheet','U236','DataRange','A2','VariableNamesRange','A1');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts);
            set(app.DropDown_236U,'Items',['Select';spikes{:,1};'Add'])
            set(app.DropDown_236U,'ItemsData',[0;(1:length(spikes{:,1}))';999])
        end

        % Value changed function: DropDown_236U
        function setSpike236U(app, event)
            value = app.DropDown_236U.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','U236');
            if value > 0 && value < 999
                set(app.Field_236URY,'Value',spikes{value,2})
                set(app.Field_236URX,'Value',spikes{value,3})
                set(app.Field_236UCY,'Value',spikes{value,4})
                set(app.Field_236UCY,'Editable','off')
                set(app.Field_236URX,'Editable','off')
                set(app.Field_236URY,'Editable','off')
                set(app.Button_Edit236U,'Visible','on')
                set(app.Button_Edit236U,'Enable','on')
                set(app.Button_Remove236U,'Enable','on')
                set(app.Button_Save236U,'Visible','off')
                set(app.Button_Save236U,'Enable','off')
            elseif value == 0
                set(app.Field_236URY,'Value',0)
                set(app.Field_236URX,'Value',0)
                set(app.Field_236UCY,'Value',0)
                set(app.Field_236UCY,'Editable','off')
                set(app.Field_236URX,'Editable','off')
                set(app.Field_236URY,'Editable','off')
                set(app.Button_Edit236U,'Visible','on')
                set(app.Button_Edit236U,'Enable','off')
                set(app.Button_Remove236U,'Enable','off')
                set(app.Button_Save236U,'Visible','off')
                set(app.Button_Save236U,'Enable','off')
            elseif value == 999
                set(app.Field_236UCY,'Editable','on')
                set(app.Field_236URX,'Editable','on')
                set(app.Field_236URY,'Editable','on')
                set(app.Button_Edit236U,'Visible','off')
                set(app.Button_Edit236U,'Enable','off')
                set(app.Button_Remove236U,'Enable','off')
                set(app.Button_Save236U,'Visible','on')
                set(app.Button_Save236U,'Enable','on')
                newspikeID = inputdlg('Enter spike ID:');
                set(app.DropDown_236U,'Items',['Select';spikes{:,1};newspikeID;'Add'])
                set(app.DropDown_236U,'ItemsData',[0;(1:length(spikes{:,1})+1)';999])
                set(app.DropDown_236U,'Value',length(spikes{:,1})+1)
                set(app.Field_236URY,'Value',0)
                set(app.Field_236URX,'Value',0)
                set(app.Field_236UCY,'Value',0)
            end
        end

        % Button pushed function: Button_Edit236U
        function edtSpike236U(app, event)
            set(app.Field_236UCY,'Editable','on')
            set(app.Field_236URX,'Editable','on')
            set(app.Field_236URY,'Editable','on')
            set(app.Button_Edit236U,'Visible','off')
            set(app.Button_Edit236U,'Enable','off')
            set(app.Button_Save236U,'Visible','on')
            set(app.Button_Save236U,'Enable','on')
        end

        % Button pushed function: Button_Save236U
        function sveSpike236U(app, event)
            value = app.DropDown_236U.Value;
            opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
            spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','U236');
            if value > length(spikes{:,1})
                spikes = [spikes;{0,0,0,0}];
                spikes{end,1} = app.DropDown_236U.Items(app.DropDown_236U.Value+1);
            end
            spikes{value,2} = app.Field_236URY.Value;
            spikes{value,3} = app.Field_236URX.Value;
            spikes{value,4} = app.Field_236UCY.Value;
            writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','U236','WriteMode','overwritesheet')
            set(app.Field_236UCY,'Editable','off')
            set(app.Field_236URX,'Editable','off')
            set(app.Field_236URY,'Editable','off')
            set(app.Button_Edit236U,'Visible','on')
            set(app.Button_Edit236U,'Enable','on')
            set(app.Button_Remove236U,'Enable','on')
            set(app.Button_Save236U,'Visible','off')
            set(app.Button_Save236U,'Enable','off')
        end

        % Button pushed function: Button_Remove236U
        function dltSpike236U(app, event)
            value = app.DropDown_236U.Value;
            % Confirm dialog
            msg = ['Do you want to delete spike ''',app.DropDown_236U.Items{value+1},''''];
            title = 'Confirm Delete';
            selection = questdlg(msg,title,'Ok','Cancel','Ok');
            if strcmp(selection,'Ok')
                opts = detectImportOptions('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx');
                spikes = readtable('/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx',opts,'Sheet','U236');
                spikes(value,:) = [];
                writetable(spikes,'/Users/jweis/MATLAB-Drive/UThProcessor/misc/spikes.xlsx','Sheet','U236','WriteMode','overwritesheet')
                set(app.DropDown_236U,'Items',['Select';spikes{:,1};'Add'])
                set(app.DropDown_236U,'ItemsData',[0;(1:length(spikes{:,1}))';999])
                app.DropDown_236U.Value = 0;
                set(app.Field_236URY,'Value',0)
                set(app.Field_236URX,'Value',0)
                set(app.Field_236UCY,'Value',0)
                set(app.Field_236UCY,'Editable','off')
                set(app.Field_236URX,'Editable','off')
                set(app.Field_236URY,'Editable','off')
                set(app.Button_Edit236U,'Visible','on')
                set(app.Button_Edit236U,'Enable','off')
                set(app.Button_Remove236U,'Enable','off')
                set(app.Button_Save236U,'Visible','off')
                set(app.Button_Save236U,'Enable','off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICProUIFigure = uifigure('Visible', 'off');
            app.ICProUIFigure.Name = 'ICPro';
            app.SampletypeSwitchLabel = uilabel(app.ICProUIFigure);
            app.SampletypeSwitchLabel.Text = 'Sample type';
            app.Panel_TE = uipanel(app.ICProUIFigure);
            app.Panel_TE.Title = 'Trace element data processing';
            app.Button_RawFile = uibutton(app.Panel_TE, 'push');
            app.Button_RawFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseTE, true);
            app.Button_RawFile.Text = '...';
            app.Field_RawFile = uieditfield(app.Panel_TE, 'text');
            app.Field_RawFile.Value = '/Users/jweis/Documents/Study/5 - PHD/5 - Misc/RA/rawfile.xlsx';
            app.Field_SIDTE = uieditfield(app.Panel_TE, 'text');
            app.Field_SIDTE.ValueChangingFcn = createCallbackFcn(app, @AutoFillSampleID, true);
            app.Field_SIDTE.Value = 'GC';
            app.Field_BlankID = uieditfield(app.Panel_TE, 'text');
            app.Field_BlankID.Value = 'Blk1';
            app.Field_SpikeID = uieditfield(app.Panel_TE, 'text');
            app.Field_SpikeID.Value = 'spike';
            app.Label_SIDTE = uilabel(app.Panel_TE);
            app.Label_SIDTE.Text = 'Sample ID initials';
            app.Label_BlankID = uilabel(app.Panel_TE);
            app.Label_BlankID.Text = 'Procedural blank ID';
            app.Label_SpikeID = uilabel(app.Panel_TE);
            app.Label_SpikeID.Text = 'Spiked sample ID';
            app.Field_RinseID = uieditfield(app.Panel_TE, 'text');
            app.Field_RinseID.Value = 'Rinse';
            app.Label_RinseID = uilabel(app.Panel_TE);
            app.Label_RinseID.Text = 'Rinse ID';
            app.Field_QCID = uieditfield(app.Panel_TE, 'text');
            app.Field_QCID.Value = 'QC';
            app.Label_QCID = uilabel(app.Panel_TE);
            app.Label_QCID.Text = 'QC ID';
            app.Panel_TECorr = uipanel(app.Panel_TE);
            app.Panel_TECorr.Title = 'Select corrections to apply';
            app.Check_RC = uicheckbox(app.Panel_TECorr);
            app.Check_RC.Text = 'Rinse correction';
            app.Check_QC = uicheckbox(app.Panel_TECorr);
            app.Check_QC.Text = 'QC correction';
            app.Check_QC.Value = true;
            app.Check_SC = uicheckbox(app.Panel_TECorr);
            app.Check_SC.Text = 'Spike correction';
            app.Check_OC = uicheckbox(app.Panel_TECorr);
            app.Check_OC.Text = 'Oxide correction';
            app.Check_BC = uicheckbox(app.Panel_TECorr);
            app.Check_BC.Text = 'Blank correction';
            app.Check_BC.Value = true;
            app.Check_DC = uicheckbox(app.Panel_TECorr);
            app.Check_DC.ValueChangedFcn = createCallbackFcn(app, @DCchange, true);
            app.Check_DC.Text = 'Dilution correction';
            app.Check_DC.Value = true;
            app.Panel_ThU = uipanel(app.ICProUIFigure);
            app.Panel_ThU.Title = 'Th/U data processing';
            app.Field_NBlocks = uieditfield(app.Panel_ThU, 'text');
            app.Field_NBlocks.Value = '8';
            app.Field_NSamples = uieditfield(app.Panel_ThU, 'text');
            app.Field_NSamples.Value = '3';
            app.Label_NBlocks = uilabel(app.Panel_ThU);
            app.Label_NBlocks.Text = 'No. of analysis blocks';
            app.Label_NSamples = uilabel(app.Panel_ThU);
            app.Label_NSamples.Text = 'No. of samples per block';
            app.Field_ICPBlankID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPBlankID.Value = 'Blank';
            app.Label_ICPBlankID = uilabel(app.Panel_ThU);
            app.Label_ICPBlankID.Text = 'ICP Blank ID';
            app.Field_ICPNatUID = uieditfield(app.Panel_ThU, 'text');
            app.Field_ICPNatUID.Value = 'NatU';
            app.Label_ICPNatUID = uilabel(app.Panel_ThU);
            app.Label_ICPNatUID.Text = 'ICP NatU ID';
            app.Field_SIDThU = uieditfield(app.Panel_ThU, 'text');
            app.Field_SIDThU.Value = 'GC';
            app.Label_SIDThU = uilabel(app.Panel_ThU);
            app.Label_SIDThU.Text = 'Sample ID initials';
            app.Button_RawFolder = uibutton(app.Panel_ThU, 'push');
            app.Button_RawFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseThU, true);
            app.Button_RawFolder.Text = '...';
            app.Field_RawFolder = uieditfield(app.Panel_ThU, 'text');
            app.Field_RawFolder.Value = '/Users/jweis/Dropbox/TAN96_97/Raw data from Ash/Ratios/Raw ICP-MS files unprocessed/31_7_20/';
            app.Panel_IDweights = uipanel(app.ICProUIFigure);
            app.Panel_IDweights.Title = 'Isotopic Dilution Input';
            app.SpikesPanel = uipanel(app.Panel_IDweights);
            app.SpikesPanel.Title = 'Spikes';
            app.UspikeDropDownLabel = uilabel(app.SpikesPanel);
            app.UspikeDropDownLabel.Text = '236U spike';
            app.DropDown_236U = uidropdown(app.SpikesPanel);
            app.DropDown_236U.Items = {'Select'};
            app.DropDown_236U.DropDownOpeningFcn = createCallbackFcn(app, @getSpikes236U, true);
            app.DropDown_236U.ValueChangedFcn = createCallbackFcn(app, @setSpike236U, true);
            app.DropDown_236U.Value = 'Select';
            app.ThspikeDropDown_2Label = uilabel(app.SpikesPanel);
            app.ThspikeDropDown_2Label.Text = '230Th spike';
            app.DropDown_230Th = uidropdown(app.SpikesPanel);
            app.DropDown_230Th.Items = {'Select'};
            app.DropDown_230Th.DropDownOpeningFcn = createCallbackFcn(app, @getSpike230Th, true);
            app.DropDown_230Th.ValueChangedFcn = createCallbackFcn(app, @setSpike230Th, true);
            app.DropDown_230Th.Value = 'Select';
            app.ThspikeDropDownLabel = uilabel(app.SpikesPanel);
            app.ThspikeDropDownLabel.Text = '229Th spike';
            app.DropDown_229Th = uidropdown(app.SpikesPanel);
            app.DropDown_229Th.Items = {'Select'};
            app.DropDown_229Th.DropDownOpeningFcn = createCallbackFcn(app, @getSpike229Th, true);
            app.DropDown_229Th.ValueChangedFcn = createCallbackFcn(app, @setSpike229Th, true);
            app.DropDown_229Th.Value = 'Select';
            app.Field_229ThRY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_229ThRY.Editable = 'off';
            app.Label_RX = uilabel(app.SpikesPanel);
            app.Label_RX.Text = 'RX';
            app.Field_229ThRX = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_229ThRX.Editable = 'off';
            app.Label_CY = uilabel(app.SpikesPanel);
            app.Label_CY.Text = 'CY';
            app.Field_229ThCY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_229ThCY.Editable = 'off';
            app.Field_230ThRY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_230ThRY.Editable = 'off';
            app.Field_236URY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_236URY.Editable = 'off';
            app.Field_230ThRX = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_230ThRX.Editable = 'off';
            app.Field_236URX = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_236URX.Editable = 'off';
            app.Field_230ThCY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_230ThCY.Editable = 'off';
            app.Field_236UCY = uieditfield(app.SpikesPanel, 'numeric');
            app.Field_236UCY.Editable = 'off';
            app.Label_RY = uilabel(app.SpikesPanel);
            app.Label_RY.Text = 'RY';
            app.Button_Edit229Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Edit229Th.ButtonPushedFcn = createCallbackFcn(app, @edtSpike229Th, true);
            app.Button_Edit229Th.Enable = 'off';
            app.Button_Edit229Th.Text = 'edit';
            app.Button_Edit230Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Edit230Th.ButtonPushedFcn = createCallbackFcn(app, @edtSpike230Th, true);
            app.Button_Edit230Th.Enable = 'off';
            app.Button_Edit230Th.Text = 'edit';
            app.Button_Edit236U = uibutton(app.SpikesPanel, 'push');
            app.Button_Edit236U.ButtonPushedFcn = createCallbackFcn(app, @edtSpike236U, true);
            app.Button_Edit236U.Enable = 'off';
            app.Button_Edit236U.Text = 'edit';
            app.Button_Save229Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Save229Th.ButtonPushedFcn = createCallbackFcn(app, @sveSpike229Th, true);
            app.Button_Save229Th.Enable = 'off';
            app.Button_Save229Th.Visible = 'off';
            app.Button_Save229Th.Text = 'save';
            app.Button_Save230Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Save230Th.ButtonPushedFcn = createCallbackFcn(app, @sveSpike230Th, true);
            app.Button_Save230Th.Enable = 'off';
            app.Button_Save230Th.Visible = 'off';
            app.Button_Save230Th.Text = 'save';
            app.Button_Save236U = uibutton(app.SpikesPanel, 'push');
            app.Button_Save236U.ButtonPushedFcn = createCallbackFcn(app, @sveSpike236U, true);
            app.Button_Save236U.Enable = 'off';
            app.Button_Save236U.Visible = 'off';
            app.Button_Save236U.Text = 'save';
            app.Button_Remove229Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Remove229Th.ButtonPushedFcn = createCallbackFcn(app, @dltSpike229Th, true);
            app.Button_Remove229Th.Enable = 'off';
            app.Button_Remove229Th.Text = 'remove';
            app.Button_Remove230Th = uibutton(app.SpikesPanel, 'push');
            app.Button_Remove230Th.ButtonPushedFcn = createCallbackFcn(app, @dltSpike230Th, true);
            app.Button_Remove230Th.Enable = 'off';
            app.Button_Remove230Th.Text = 'remove';
            app.Button_Remove236U = uibutton(app.SpikesPanel, 'push');
            app.Button_Remove236U.ButtonPushedFcn = createCallbackFcn(app, @dltSpike236U, true);
            app.Button_Remove236U.Enable = 'off';
            app.Button_Remove236U.Text = 'remove';
            app.Footnote = uilabel(app.SpikesPanel);
            app.Footnote.Text = 'RY: isotope amount ratio of spike, RX: isotope amount ratio of sample, CY: spike concentration (pg/g)';
            app.TabGroup = uitabgroup(app.Panel_IDweights);
            app.WeightsmgTab = uitab(app.TabGroup);
            app.WeightsmgTab.Title = 'Weights [mg]';
            app.Field_Sediment = uitextarea(app.WeightsmgTab);
            app.Field_Sediment.Value = {'191.5'; '176.2'; '114.2'; '189.5'; '124.2'; '210.2'; '156.6'; '175.3'; '172.9'; '179.3'; '172.6'; '172.1'; '172.7'; '191.6'; '173.9'; '179.8'; '176.7'; '177.2'; '197.1'; '196.9'; '151.3'; '191.7'; '142.8'};
            app.Label_Sediment = uilabel(app.WeightsmgTab);
            app.Label_Sediment.Text = 'Sediment';
            app.Field_4ml = uitextarea(app.WeightsmgTab);
            app.Field_4ml.Value = {'4864.3'; '5127.3'; '5075.2'; '4942.8'; '4840.4'; '5113.5'; '4985.4'; '4911.7'; '5000.8'; '4940.1'; '4935.8'; '4899.4'; '5023.7'; '4865.2'; '4665.8'; '4928'; '4851.4'; '4919.4'; '4917.9'; '4900.7'; '4938.7'; '4756.5'; '4901.6'};
            app.Field_10ml = uitextarea(app.WeightsmgTab);
            app.Field_10ml.Value = {'10452.4'; '10436.4'; '10413.8'; '10438.6'; '10415.9'; '10427.6'; '10452.4'; '10439.6'; '10458.3'; '10467.5'; '10449.3'; '10471'; '10473.8'; '10443.1'; '10471'; '10462.1'; '10441.6'; '10480'; '10485.4'; '10486.6'; '10493.3'; '10484.1'; '10489.6'};
            app.Field_400ul = uitextarea(app.WeightsmgTab);
            app.Field_400ul.Value = {'506.7'; '519'; '512.8'; '512.4'; '502.1'; '513.4'; '510.9'; '512.6'; '516'; '518.6'; '511.4'; '511.4'; '507.6'; '510.4'; '520.3'; '506.2'; '515.7'; '516'; '512.6'; '510.3'; '514.3'; '511.7'; '511.2'};
            app.Field_ThBC = uitextarea(app.WeightsmgTab);
            app.Field_ThBC.Value = {'514.4'; '517.1'; '515.9'; '516.3'; '517.7'; '516.7'; '515.4'; '515.5'; '517'; '515.5'; '518.9'; '519'; '516.3'; '514.6'; '515.3'; '516.2'; '513'; '518'; '515.1'; '515.7'; '515.4'; '515.7'; '516.9'};
            app.Label_4ml = uilabel(app.WeightsmgTab);
            app.Label_4ml.Text = '4 ml';
            app.Label_10ml = uilabel(app.WeightsmgTab);
            app.Label_10ml.Text = '10 ml';
            app.Label_400ul = uilabel(app.WeightsmgTab);
            app.Label_400ul.Text = '400 ul';
            app.Label_ThBC = uilabel(app.WeightsmgTab);
            app.Label_ThBC.Text = '229Th (BC)';
            app.Field_UBC = uitextarea(app.WeightsmgTab);
            app.Field_UBC.Value = {'9.4'; '10.3'; '8.8'; '11.3'; '10'; '10'; '10.7'; '9.8'; '10.2'; '10.1'; '10'; '10.4'; '9.9'; '10'; '10.2'; '10.1'; '9.5'; '9.8'; '10.2'; '10.8'; '9.2'; '10.1'; '10.1'};
            app.Field_ThSC = uitextarea(app.WeightsmgTab);
            app.Field_ThSC.Value = {'41.8'; '42.4'; '41.9'; '41.9'; '41.7'; '41.8'; '41.9'; '41.9'; '41.9'; '41.9'; '41.7'; '42.1'; '42'; '41.9'; '42'; '42.3'; '41.8'; '41.8'; '41.9'; '41.8'; '41.9'; '41.7'; '42'};
            app.Label_UBC = uilabel(app.WeightsmgTab);
            app.Label_UBC.Text = '236U (BC)';
            app.Label_ThSC = uilabel(app.WeightsmgTab);
            app.Label_ThSC.Text = '230Th (SC)';
            app.Field_USC = uitextarea(app.WeightsmgTab);
            app.Field_USC.Value = {'155'; '155'; '155'; '154.7'; '154.7'; '154.9'; '154.8'; '154.8'; '155'; '154.9'; '154.8'; '154.9'; '155.3'; '155.3'; '155.2'; '155.5'; '155.7'; '155.3'; '155.4'; '155.1'; '155.5'; '155.3'; '155.2'};
            app.Label_USC = uilabel(app.WeightsmgTab);
            app.Label_USC.Text = '236U (SC)';
            app.Field_SampleList = uitextarea(app.WeightsmgTab);
            app.Field_SampleList.Editable = 'off';
            app.Field_SampleList.Visible = 'off';
            app.Label_SampleList = uilabel(app.WeightsmgTab);
            app.Label_SampleList.Text = 'ID';
            app.Button_MakeList = uibutton(app.WeightsmgTab, 'push');
            app.Button_MakeList.ButtonPushedFcn = createCallbackFcn(app, @MakeSampleList, true);
            app.Button_MakeList.Text = 'Generate';
            app.ThUrawintensitiescpsTab = uitab(app.TabGroup);
            app.ThUrawintensitiescpsTab.Title = 'Th & U raw intensities [cps]';
            app.Field_cpsSampleList = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cpsSampleList.Editable = 'off';
            app.Field_cpsSampleList.Visible = 'off';
            app.Label_cpsSampleList = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cpsSampleList.Text = 'ID';
            app.Field_cps230Th = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps230Th.Value = {'20806.5'; '19336.4'; '19361.7'; '18926.5'; '19091'; '19371.8'; '19354.1'; '20201.7'; '19536.3'; '21218.9'; '20308'; '20308'; '20173.9'; '20892.5'; '20831.8'; '19903.1'; '20568.6'; '19538.8'; '20381.4'; '20280.2'; '19374.3'; '20442.1'; '18989.7'};
            app.Label_cps230Th = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps230Th.Text = 'Th230';
            app.Field_cps232Th = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps232Th.Value = {'100896.2'; '120490.6'; '73729'; '92745'; '51257.6'; '485562.1'; '127936.5'; '201961.3'; '57485.4'; '173318.8'; '95274'; '95274'; '103194.5'; '240709'; '97380.2'; '123523.4'; '127700.7'; '88330.6'; '123802.4'; '89725.6'; '102994.4'; '93909.5'; '64126.3'};
            app.Field_cps236U = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps236U.Value = {'78428.6'; '72847.9'; '71094'; '71812.7'; '71995'; '71906.2'; '73432.5'; '74470.3'; '74396.8'; '76166'; '75381.3'; '75381.3'; '76289.9'; '80481.4'; '76140.8'; '75191.7'; '75052.2'; '74680.3'; '75490.1'; '76461.9'; '74293'; '76138.1'; '76155.8'};
            app.Field_cps238U = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps238U.Value = {'48155'; '59806.1'; '32169.9'; '48225.9'; '25818.8'; '103468.2'; '46808.9'; '49167.3'; '37488.8'; '55526.7'; '46383.7'; '46383.7'; '58553.5'; '69803.1'; '53674.3'; '71303.8'; '59963'; '56807.2'; '69630.2'; '49210.3'; '46517.7'; '48203.2'; '50589.4'};
            app.Label_cps232Th = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps232Th.Text = '232Th';
            app.Label_cps236U = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps236U.Text = '236U';
            app.Label_cps238U = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps238U.Text = '238U';
            app.Label_cps230Th_R = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps230Th_R.Text = 'Th230 Rinse';
            app.Label_cps232Th_R = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps232Th_R.Text = '232Th Rinse';
            app.Label_cps236U_R = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps236U_R.Text = '236U Rinse';
            app.Label_cps238U_R = uilabel(app.ThUrawintensitiescpsTab);
            app.Label_cps238U_R.Text = '238U Rinse';
            app.Field_cps230Th_R = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps230Th_R.Value = {'optional'};
            app.Field_cps232Th_R = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps232Th_R.Value = {'optional'};
            app.Field_cps236U_R = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps236U_R.Value = {'optional'};
            app.Field_cps238U_R = uitextarea(app.ThUrawintensitiescpsTab);
            app.Field_cps238U_R.Value = {'optional'};
            app.Footnote2 = uilabel(app.ThUrawintensitiescpsTab);
            app.Footnote2.Text = 'Rinse raw intensities are required for rinse correction. If not specified, Th and U raw intensities will not be rinse corrected.';
            app.Button_TEtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_TEtoggle.ValueChangedFcn = createCallbackFcn(app, @ToggleTE, true);
            app.Button_TEtoggle.Tooltip = {''};
            app.Button_TEtoggle.Icon = 'tick.png';
            app.Button_TEtoggle.Text = '';
            app.Button_TEtoggle.Value = true;
            app.Button_ThUtoggle = uibutton(app.ICProUIFigure, 'state');
            app.Button_ThUtoggle.ValueChangedFcn = createCallbackFcn(app, @ToggleThU, true);
            app.Button_ThUtoggle.Tooltip = {''};
            app.Button_ThUtoggle.Icon = 'tick.png';
            app.Button_ThUtoggle.Text = '';
            app.Button_ThUtoggle.Value = true;
            app.Button_Run = uibutton(app.ICProUIFigure, 'push');
            app.Button_Run.ButtonPushedFcn = createCallbackFcn(app, @Execute, true);
            app.Button_Run.Icon = 'go.png';
            app.Button_Run.Text = '';
            app.Switch_SType = uiswitch(app.ICProUIFigure, 'slider');
            app.Switch_SType.Items = {'Sediment', 'Sea water'};
            app.Switch_SType.Value = 'Sediment';
            % Show the figure after all components are created
            app.ICProUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICProUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICProUIFigure)
        end
    end
end